classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        ConfigurationPanel             matlab.ui.container.Panel
        segmentationTSlider            matlab.ui.control.Slider
        NLabel_2                       matlab.ui.control.Label
        n_cSlider                      matlab.ui.control.Slider
        NLabel                         matlab.ui.control.Label
        edgeTSlider                    matlab.ui.control.Slider
        EdgeThresholdLabel             matlab.ui.control.Label
        preprocessSlider               matlab.ui.control.Slider
        BlurLevelLabel                 matlab.ui.control.Label
        SegmentationMethodDropDown     matlab.ui.control.DropDown
        SegmentationMethodDropDownLabel  matlab.ui.control.Label
        EdgeDetectionMethodDropDown    matlab.ui.control.DropDown
        EdgeDetectionMethodDropDownLabel  matlab.ui.control.Label
        PreprocessMethodDropDown       matlab.ui.control.DropDown
        PreprocessMethodDropDownLabel  matlab.ui.control.Label
        Panel_2                        matlab.ui.container.Panel
        LoadImageButton                matlab.ui.control.Button
        ResultLabel                    matlab.ui.control.Label
        EdgeLabel_2                    matlab.ui.control.Label
        InputLabel                     matlab.ui.control.Label
        ImageSource                    matlab.ui.control.Image
        ImageEdge                      matlab.ui.control.Image
        ImageResult                    matlab.ui.control.Image
    end

    
    properties (Access = private)
        CurrentImage
        GrayImage
        BlurImage
        EdgeImage
        MaskImage
        ResultImage
        Loaded = false
    end
    
    methods (Access = private)
        
        % Fungsi utama
        function Generate(app)
            if (app.Loaded)
                % Ubah gambar masukan menjadi grayscale
                app.GrayImage = rgb2gray(app.CurrentImage);
        
                % Lakukan blurring untuk menghilangkan derau
                app.BlurImage = app.GenerateBlurImage(app.GrayImage, app.PreprocessMethodDropDown.Value, app.preprocessSlider.Value);


                % Buat gambar biner tepi dengan teknik deteksi tepi yang
                % dipilih
                app.EdgeImage = app.GenerateEdgeImage(app.BlurImage, app.EdgeDetectionMethodDropDown.Value, app.edgeTSlider.Value, app.n_cSlider.Value);
                app.ImageEdge.ImageSource = cat(3, app.EdgeImage, app.EdgeImage, app.EdgeImage) * 255;
    
                % Buat gambar biner mask dengan teknik segmentasi (region
                % filling) yang dipilih
                app.MaskImage = app.GenerateMaskImage(app.EdgeImage, app.SegmentationMethodDropDown.Value, app.segmentationTSlider.Value);
    
                % Terapkan gambar biner mask pada gambar masukan
                app.ResultImage = app.CurrentImage .* app.MaskImage;
                app.ImageResult.ImageSource = app.ResultImage;
            end
        end

        function result = GenerateEdgeImage(~, inputImage, edgeDetectionMethod, threshold, n_c)
            switch edgeDetectionMethod

                % Laplace
                case 'Laplace'
                    result = functionsHelper.Laplace(inputImage, threshold);

                % Laplacian of Gaussian
                case 'LoG'
                    % Ukuran filter
                    result = functionsHelper.LoG(inputImage, n_c, threshold);

                % Sobel
                case 'Sobel'
                    result = functionsHelper.Sobel(inputImage, n_c, threshold);

                % Prewitt
                case 'Prewitt'
                    result = functionsHelper.Prewitt(inputImage, threshold);

                % Roberts
                case 'Roberts'
                    result = functionsHelper.Roberts(inputImage, threshold);

                % Canny
                case 'Canny'
                    result = functionsHelper.Canny(inputImage, threshold);

                otherwise
                    result = inputImage;
            end
        end

        % Fungsi blurring (pemrosesan awal)
        function result = GenerateBlurImage(~, inputImage, edgeDetectionMethod, aLevel)
            if (aLevel > 0)
                switch edgeDetectionMethod

                    % Blurring dengan filter disk
                    case 'Disk Filter'
                        result = imfilter(inputImage, fspecial('disk', aLevel), 'replicate');

                    % Blurring dengan filter gaussian
                    case 'Gaussian Filter'
                        result = imgaussfilt(inputImage, aLevel);

                    otherwise
                        result = inputImage;
                end
            else
                result = inputImage;
            end
        end

        % Fungsi pembuatan gambar biner mask
        function result = GenerateMaskImage(~, inputImage, edgeDetectionMethod, threshold)
            switch edgeDetectionMethod

                % Buat mask dengan metode 1, untuk gambar dengan banyak
                % tepi
                case 'Dilate-Thin-Fill-Erode'

     
                    cleanImage = imclearborder(inputImage);

                    cleanImage = imdilate(cleanImage, strel('disk', round(threshold)));
                    cleanImage = bwmorph(cleanImage, 'thin', Inf);


                    cleanImage = imfill(cleanImage, 'holes');

                    cleanImage = imerode(cleanImage, strel('disk', 2));

                    result = uint8(cleanImage);

                % Buat mask dengan metode 2, untuk gambar sederhana
                case 'Close-Fill-Erode'


                    cleanImage = imclearborder(inputImage);

                    cleanImage = imclose(cleanImage, strel('disk', round(threshold)));

                    cleanImage = imfill(cleanImage, 8, 'holes');

                    cleanImage = imerode(cleanImage, strel('disk', 2));

                    result = uint8(cleanImage);
                    
                otherwise
                    result = uint8(ones([size(inputImage, 1), size(inputImage, 2)]));
            end
        end

        function ToggleSliders(app)
            switch app.EdgeDetectionMethodDropDown.Value
                case 'LoG'
                    app.n_cSlider.Enable = 'on';
                case 'Sobel'
                    app.n_cSlider.Enable = 'on';
                otherwise
                    app.n_cSlider.Enable = 'off';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ToggleSliders();
        end

        % Value changed function: EdgeDetectionMethodDropDown, 
        % ...and 6 other components
        function SegmentationMethodDropDownValueChanged(app, event)
            app.ToggleSliders();
            app.Generate();
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [file,path] = uigetfile({'*.png;*.jpg;*.jpeg','Images'});
            if (file ~= 0)
                app.CurrentImage = imread(fullfile(path,file));
                app.Loaded = true;
                app.ImageSource.ImageSource = app.CurrentImage;
                app.Generate();
                figure(app.UIFigure);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel_2 = uipanel(app.Panel);
            app.ImageResult = uiimage(app.Panel_2);
            app.ImageEdge = uiimage(app.Panel_2);
            app.ImageSource = uiimage(app.Panel_2);
            app.InputLabel = uilabel(app.Panel_2);
            app.InputLabel.Text = 'Input';
            app.EdgeLabel_2 = uilabel(app.Panel_2);
            app.EdgeLabel_2.Text = 'Edge';
            app.ResultLabel = uilabel(app.Panel_2);
            app.ResultLabel.Text = 'Result';
            app.LoadImageButton = uibutton(app.Panel_2, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ConfigurationPanel = uipanel(app.Panel);
            app.ConfigurationPanel.Title = 'Configuration';
            app.PreprocessMethodDropDownLabel = uilabel(app.ConfigurationPanel);
            app.PreprocessMethodDropDownLabel.Text = 'Preprocess Method';
            app.PreprocessMethodDropDown = uidropdown(app.ConfigurationPanel);
            app.PreprocessMethodDropDown.Items = {'Gaussian Filter', 'Disk Filter'};
            app.PreprocessMethodDropDown.Editable = 'on';
            app.PreprocessMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SegmentationMethodDropDownValueChanged, true);
            app.PreprocessMethodDropDown.Value = 'Gaussian Filter';
            app.EdgeDetectionMethodDropDownLabel = uilabel(app.ConfigurationPanel);
            app.EdgeDetectionMethodDropDownLabel.Text = 'Edge Detection Method';
            app.EdgeDetectionMethodDropDown = uidropdown(app.ConfigurationPanel);
            app.EdgeDetectionMethodDropDown.Items = {'Laplace', 'LoG', 'Sobel', 'Prewitt', 'Roberts', 'Canny'};
            app.EdgeDetectionMethodDropDown.Editable = 'on';
            app.EdgeDetectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SegmentationMethodDropDownValueChanged, true);
            app.EdgeDetectionMethodDropDown.Value = 'Canny';
            app.SegmentationMethodDropDownLabel = uilabel(app.ConfigurationPanel);
            app.SegmentationMethodDropDownLabel.Text = 'Segmentation Method';
            app.SegmentationMethodDropDown = uidropdown(app.ConfigurationPanel);
            app.SegmentationMethodDropDown.Items = {'Dilate-Thin-Fill-Erode', 'Close-Fill-Erode'};
            app.SegmentationMethodDropDown.Editable = 'on';
            app.SegmentationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SegmentationMethodDropDownValueChanged, true);
            app.SegmentationMethodDropDown.Value = 'Close-Fill-Erode';
            app.BlurLevelLabel = uilabel(app.ConfigurationPanel);
            app.BlurLevelLabel.Text = 'Preprocess Level';
            app.preprocessSlider = uislider(app.ConfigurationPanel);
            app.preprocessSlider.Limits = [0 25];
            app.preprocessSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25];
            app.preprocessSlider.MajorTickLabels = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25'};
            app.preprocessSlider.ValueChangedFcn = createCallbackFcn(app, @SegmentationMethodDropDownValueChanged, true);
            app.preprocessSlider.MinorTicks = [];
            app.EdgeThresholdLabel = uilabel(app.ConfigurationPanel);
            app.EdgeThresholdLabel.Text = 'Edge Threshold';
            app.edgeTSlider = uislider(app.ConfigurationPanel);
            app.edgeTSlider.Limits = [0 255];
            app.edgeTSlider.MajorTicks = [0 15 30 45 60 75 90 105 120 135 150 165 180 195 210 225 240 255];
            app.edgeTSlider.MajorTickLabels = {'0', '15', '30', '45', '60', '75', '90', '105', '120', '135', '150', '165', '180', '195', '210', '225', '240', '255'};
            app.edgeTSlider.ValueChangedFcn = createCallbackFcn(app, @SegmentationMethodDropDownValueChanged, true);
            app.edgeTSlider.MinorTicks = [];
            app.edgeTSlider.Value = 16;
            app.NLabel = uilabel(app.ConfigurationPanel);
            app.NLabel.Text = 'N (LoG) / C (Sobel)';
            app.n_cSlider = uislider(app.ConfigurationPanel);
            app.n_cSlider.Limits = [0 25];
            app.n_cSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25];
            app.n_cSlider.MajorTickLabels = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25'};
            app.n_cSlider.ValueChangedFcn = createCallbackFcn(app, @SegmentationMethodDropDownValueChanged, true);
            app.n_cSlider.MinorTicks = [];
            app.n_cSlider.Value = 2;
            app.NLabel_2 = uilabel(app.ConfigurationPanel);
            app.NLabel_2.Text = 'Segmentation Threshold';
            app.segmentationTSlider = uislider(app.ConfigurationPanel);
            app.segmentationTSlider.MajorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.segmentationTSlider.MajorTickLabels = {'0', '4', '8', '12', '16', '20', '24', '28', '32', '36', '40', '44', '48', '52', '56', '60', '64', '68', '72', '76', '80', '84', '88', '92', '96', '100'};
            app.segmentationTSlider.ValueChangedFcn = createCallbackFcn(app, @SegmentationMethodDropDownValueChanged, true);
            app.segmentationTSlider.MinorTicks = [];
            app.segmentationTSlider.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end